classdef interpolation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        InterpolateButton              matlab.ui.control.Button
        ChooseButton                   matlab.ui.control.Button
        ARCFileLocationEditField       matlab.ui.control.EditField
        ARCFileLocationEditFieldLabel  matlab.ui.control.Label
        ForcestoInterpolateLabel       matlab.ui.control.Label
        Tree                           matlab.ui.container.CheckBoxTree
        BodyForcesNode                 matlab.ui.container.TreeNode
        FxNode                         matlab.ui.container.TreeNode
        FyNode                         matlab.ui.container.TreeNode
        FzNode                         matlab.ui.container.TreeNode
        MxNode                         matlab.ui.container.TreeNode
        MyNode                         matlab.ui.container.TreeNode
        MzNode                         matlab.ui.container.TreeNode
        WheelForcesNode                matlab.ui.container.TreeNode
        WheelFxNode                    matlab.ui.container.TreeNode
        WheelFyNode                    matlab.ui.container.TreeNode
        WheelFzNode                    matlab.ui.container.TreeNode
        WheelMxNode                    matlab.ui.container.TreeNode
        WheelMyNode                    matlab.ui.container.TreeNode
        WheelMzNode                    matlab.ui.container.TreeNode
        RightPanel                     matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ARCtable % interpolation table of arc data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            try
%                 if app.FxNode == 1
%                     load app.arcFile Fx
%                 end
%                 if app.FyNode == 1
%                     load app.arcFile Fy
%                 end
%                 if app.FzNode == 1
%                     load app.arcFile Fz
%                 end
%                 if app.MxNode == 1
%                     load app.arcFile Mx
%                 end
%                 if app.MyNode == 1
%                     load app.arcFile My
%                 end
%                 if app.MzNode == 1
%                     load app.arcFile Mz
%                 end
%                 if app.WheelFxNode == 1
%                     load app.arcFile WheelFx
%                 end
%                 if app.WheelFyNode == 1
%                     load app.arcFile WheelFy
%                 end
%                 if app.WheelFzNode == 1
%                     load app.arcFile WheelFz
%                 end
%                 if app.WheelMxNode == 1
%                     load app.arcFile WheelMx
%                 end
%                 if app.WheelMyNode == 1
%                     load app.arcFile WheelMy
%                 end
%                 if app.WheelMzNode == 1
%                     load app.arcFile WheelMz
%                 end
            catch
                % just wait until user enters in the file to do anything
                % no need to print out a response
            end
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            [ARCfile,ARCpath] = uigetfile("*.mat",...
                'Select the ARC Interpolation Table',"ARC_Interpolation_Table.mat");
            app.ARCFileLocationEditField.Value = ARCpath;
            load (ARCfile)
            app.ARCtable = interpHydro;

        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
            try
                fields = fieldnames(app.ARCtable);
                app.UITable.Data = {fields{1},app.ARCtable.(fields{1}).Center.Values(1)};
            catch
                fig = uifigure;
                selection = uiconfirm(fig,"ARC file not found!",...
                    "Invalid File","Icon","error");
                switch selection
                    case 'OK'
                        delete(fig);
                        pause(1); % wait a second before opening the window
                        ChooseButtonPushed(app,event);
                    case 'Cancel'
                        return % exit the sequence
                end 
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {504, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.BodyForcesNode = uitreenode(app.Tree);
            app.BodyForcesNode.Text = 'Body Forces';
            app.FxNode = uitreenode(app.BodyForcesNode);
            app.FxNode.Text = 'Fx';
            app.FyNode = uitreenode(app.BodyForcesNode);
            app.FyNode.Text = 'Fy';
            app.FzNode = uitreenode(app.BodyForcesNode);
            app.FzNode.Text = 'Fz';
            app.MxNode = uitreenode(app.BodyForcesNode);
            app.MxNode.Text = 'Mx';
            app.MyNode = uitreenode(app.BodyForcesNode);
            app.MyNode.Text = 'My';
            app.MzNode = uitreenode(app.BodyForcesNode);
            app.MzNode.Text = 'Mz';
            app.WheelForcesNode = uitreenode(app.Tree);
            app.WheelForcesNode.Text = 'Wheel Forces';
            app.WheelFxNode = uitreenode(app.WheelForcesNode);
            app.WheelFxNode.Text = 'Wheel Fx';
            app.WheelFyNode = uitreenode(app.WheelForcesNode);
            app.WheelFyNode.Text = 'Wheel Fy';
            app.WheelFzNode = uitreenode(app.WheelForcesNode);
            app.WheelFzNode.Text = 'Wheel Fz';
            app.WheelMxNode = uitreenode(app.WheelForcesNode);
            app.WheelMxNode.Text = 'Wheel Mx';
            app.WheelMyNode = uitreenode(app.WheelForcesNode);
            app.WheelMyNode.Text = 'Wheel My';
            app.WheelMzNode = uitreenode(app.WheelForcesNode);
            app.WheelMzNode.Text = 'Wheel Mz';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.ForcestoInterpolateLabel = uilabel(app.LeftPanel);
            app.ForcestoInterpolateLabel.Text = 'Forces to Interpolate';
            app.ARCFileLocationEditFieldLabel = uilabel(app.LeftPanel);
            app.ARCFileLocationEditFieldLabel.Text = {'ARC File'; 'Location'};
            app.ARCFileLocationEditField = uieditfield(app.LeftPanel, 'text');
            app.ChooseButton = uibutton(app.LeftPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.InterpolateButton = uibutton(app.LeftPanel, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Variable'; 'Value'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interpolation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end